classdef Caracterizacion_de_Termopares < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CaracterizacindeTermoparesUIFigure  matlab.ui.Figure
        SeleccionarArchivoLabel         matlab.ui.control.Label
        AbrirButton                     matlab.ui.control.Button
        CalcularporButtonGroup          matlab.ui.container.ButtonGroup
        GradoButton                     matlab.ui.control.RadioButton
        CoeficientedePearsonButton      matlab.ui.control.RadioButton
        TemperaturacomovariableButtonGroup  matlab.ui.container.ButtonGroup
        TVButton                        matlab.ui.control.RadioButton
        VTButton                        matlab.ui.control.RadioButton
        DatoscargadosLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        GradodelPolinomioLabel          matlab.ui.control.Label
        CoeficientedePearsonmnimoLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        ResultadosLabel                 matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        CoeficientedecorrelacinPearsonLabel  matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        FileName                        matlab.ui.control.EditField
        DatosCargadosTable              matlab.ui.control.Table
    end

    methods (Access = private)

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');   
            if isequal(file,0)
               disp('Se canceló la selección de archivo del usuario');
               app.FileName.Value = 'No se ha seleccionado ningún archivo';
               app.DatosCargadosTable.delete;
            else
               disp(['Se importo el archivo: ', fullfile(path,file)]);
               app.FileName.Value = file;
               app.DatosCargadosTable = readtable(file,'%f %f', '%f %u', '%u %u');
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            !if floor(value) ~= value
                !warndlg('El valor del grado debe ser un numero entero');
            !end
        end

        % Selection changed function: CalcularporButtonGroup
        function CalcularporButtonGroupSelectionChanged(app, event)

            selectedButton = app.CalcularporButtonGroup.SelectedObject;
            if selectedButton == app.GradoButton
                set(app.GradodelPolinomioLabel,'visible',1);
                set(app.EditField,'visible', 1);
                set(app.CoeficientedePearsonmnimoLabel,'visible',0);
                set(app.DropDown,'visible',0);
            else
                set(app.GradodelPolinomioLabel,'visible',0);
                set(app.EditField,'visible', 0);
                set(app.CoeficientedePearsonmnimoLabel,'visible',1);
                set(app.DropDown,'visible',1);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CaracterizacindeTermoparesUIFigure = uifigure;
            app.CaracterizacindeTermoparesUIFigure.Name = 'Caracterización de Termopares';
            app.SeleccionarArchivoLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.SeleccionarArchivoLabel.Text = 'Seleccionar Archivo';
            app.AbrirButton = uibutton(app.CaracterizacindeTermoparesUIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.CalcularporButtonGroup = uibuttongroup(app.CaracterizacindeTermoparesUIFigure);
            app.CalcularporButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalcularporButtonGroupSelectionChanged, true);
            app.CalcularporButtonGroup.Title = 'Calcular por:';
            app.GradoButton = uiradiobutton(app.CalcularporButtonGroup);
            app.GradoButton.Text = 'Grado';
            app.GradoButton.Value = true;
            app.CoeficientedePearsonButton = uiradiobutton(app.CalcularporButtonGroup);
            app.CoeficientedePearsonButton.Text = 'Coeficiente de Pearson';
            app.TemperaturacomovariableButtonGroup = uibuttongroup(app.CaracterizacindeTermoparesUIFigure);
            app.TemperaturacomovariableButtonGroup.Title = 'Temperatura como variable';
            app.TVButton = uiradiobutton(app.TemperaturacomovariableButtonGroup);
            app.TVButton.Text = 'T(V)';
            app.TVButton.Value = true;
            app.VTButton = uiradiobutton(app.TemperaturacomovariableButtonGroup);
            app.VTButton.Text = 'V(T)';
            app.DatoscargadosLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.DatoscargadosLabel.Text = 'Datos cargados';
            app.UIAxes = uiaxes(app.CaracterizacindeTermoparesUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GradodelPolinomioLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.GradodelPolinomioLabel.Text = 'Grado del Polinomio';
            app.CoeficientedePearsonmnimoLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.CoeficientedePearsonmnimoLabel.Visible = 'off';
            app.CoeficientedePearsonmnimoLabel.Text = 'Coeficiente de Pearson mínimo ';
            app.EditField = uieditfield(app.CaracterizacindeTermoparesUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DropDown = uidropdown(app.CaracterizacindeTermoparesUIFigure);
            app.DropDown.Items = {'0.85', '0.9', '0.95', '0.99', '0.995', '0.999'};
            app.DropDown.ItemsData = {'0.85', '0.9', '0.95', '0.99', '0.995', '0.999'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = '0.85';
            app.ResultadosLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.ListBox = uilistbox(app.CaracterizacindeTermoparesUIFigure);
            app.CoeficientedecorrelacinPearsonLabel = uilabel(app.CaracterizacindeTermoparesUIFigure);
            app.CoeficientedecorrelacinPearsonLabel.Text = 'Coeficiente de correlación (Pearson)';
            app.EditField_2 = uieditfield(app.CaracterizacindeTermoparesUIFigure, 'numeric');
            app.FileName = uieditfield(app.CaracterizacindeTermoparesUIFigure, 'text');
            app.FileName.Editable = 'off';
            app.FileName.Value = 'No se ha seleccionado ningún archivo';
            app.DatosCargadosTable = uitable(app.CaracterizacindeTermoparesUIFigure);
            app.DatosCargadosTable.ColumnName = {'Minivoltios (mV)'; 'Temperatura (°C)'};
            app.DatosCargadosTable.RowName = {};
            app.DatosCargadosTable.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Caracterizacion_de_Termopares

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CaracterizacindeTermoparesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CaracterizacindeTermoparesUIFigure)
        end
    end
end